classdef ECE4950GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RemoveHoleMarkersLabel         matlab.ui.control.Label
        PlaceHoleMarkersandBallLabel   matlab.ui.control.Label
        CaptureImageofBoardButton      matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        ValueisLabel                   matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SimulationTimeEditField        slrealtime.ui.control.SimulationTimeEditField
        ChooseaTargetColorButtonGroup  matlab.ui.container.ButtonGroup
        ChooseButton                   matlab.ui.control.RadioButton
        RedButton                      matlab.ui.control.RadioButton
        GreyButton                     matlab.ui.control.RadioButton
        BlueButton                     matlab.ui.control.RadioButton
        StartButton                    matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function startButton(app, event)
            global selectedButton;
            app.Label.Text = selectedButton.Text;
            run("Project4.m");
            %run("Project4.m/Testing()")
            %run("Project4.m");
        end

        % Selection changed function: ChooseaTargetColorButtonGroup
        function colorChanged(app, event)
            global selectedButton;
            selectedButton = app.ChooseaTargetColorButtonGroup.SelectedObject;
            %disp(selectedButton);
            
        end

        % Button pushed function: CaptureImageofBoardButton
        function imageCaptureButton(app, event)

            cam_list = webcamlist;
            cam_name = cam_list{1};
            cam = webcam(cam_name);
            
            rgb = snapshot(cam);
            
            
            imwrite(rgb, "background.png")
            %
            %imageCapture();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @startButton, true);
            app.StartButton.Text = '5. Start';
            app.ChooseaTargetColorButtonGroup = uibuttongroup(app.UIFigure);
            app.ChooseaTargetColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @colorChanged, true);
            app.ChooseaTargetColorButtonGroup.Title = '4. Choose a Target Color';
            app.BlueButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.GreyButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.GreyButton.Text = 'Grey';
            app.RedButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.RedButton.Text = 'Red';
            app.ChooseButton = uiradiobutton(app.ChooseaTargetColorButtonGroup);
            app.ChooseButton.Enable = 'off';
            app.ChooseButton.Visible = 'off';
            app.ChooseButton.Text = 'Choose';
            app.ChooseButton.Value = true;
            app.SimulationTimeEditField = slrealtime.ui.control.SimulationTimeEditField(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'PutPutObjects.jpg';
            app.ValueisLabel = uilabel(app.UIFigure);
            app.ValueisLabel.Text = 'Value is: ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CaptureImageofBoardButton = uibutton(app.UIFigure, 'push');
            app.CaptureImageofBoardButton.ButtonPushedFcn = createCallbackFcn(app, @imageCaptureButton, true);
            app.CaptureImageofBoardButton.Text = '2. Capture Image of Board';
            app.PlaceHoleMarkersandBallLabel = uilabel(app.UIFigure);
            app.PlaceHoleMarkersandBallLabel.Text = '1. Place Hole Markers and Ball';
            app.RemoveHoleMarkersLabel = uilabel(app.UIFigure);
            app.RemoveHoleMarkersLabel.Text = {'3. Remove Hole Markers'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECE4950GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end